classdef testflight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioPlayerByDanielMizrahiUIFigure  matlab.ui.Figure
        SamplingFrequencyEditField  matlab.ui.control.EditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        VolumeKnob                  matlab.ui.control.Knob
        VolumeKnobLabel             matlab.ui.control.Label
        MuteSwitch                  matlab.ui.control.ToggleSwitch
        MuteSwitchLabel             matlab.ui.control.Label
        PlaybackSpeedKnob           matlab.ui.control.Knob
        PlaybackSpeedKnobLabel      matlab.ui.control.Label
        MuteLamp                    matlab.ui.control.Lamp
        Boost20dBSwitch             matlab.ui.control.ToggleSwitch
        Boost20dBSwitchLabel        matlab.ui.control.Label
        AudioStatusLamp             matlab.ui.control.Lamp
        RECLabel                    matlab.ui.control.Label
        StopAudioButton             matlab.ui.control.Button
        ResumeAudioButton           matlab.ui.control.Button
        PauseAudioButton            matlab.ui.control.Button
        PlayAudioButton             matlab.ui.control.Button
        LoadFileButton              matlab.ui.control.Button
        AudioSpectrogram3D          matlab.ui.control.UIAxes
        AudioSpectrogram            matlab.ui.control.UIAxes
        AudioWaveform               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Audio File Class

        player
        fileName
        pathName
        y
        Fs
        yStatic
        currentSample
        flagR
        flagM
        flagS
        flagSTOPPLOTING

    end

    methods (Access = private)

        function specPlot3D(app)
            %3d spectogram graph
            signal=app.y(:,1);
            fs=app.Fs;
            [S,F,T] = spectrogram(signal,1024,512,1024,fs);
            surf(app.AudioSpectrogram3D, T, F, log(abs(S)));
            set(app.AudioSpectrogram3D,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            axis(app.AudioSpectrogram3D,'tight');
            axis(app.AudioSpectrogram3D,'square');
            colormap(app.AudioSpectrogram3D,'jet');
            shading(app.AudioSpectrogram3D,'interp');
            view(app.AudioSpectrogram3D,135,40)
        end

        function specPlot2D(app)
            %3d spectogram graph
            signal=app.y(:,1);
            fs=app.Fs;
            %spectorgram graph
            [S,F,T] = spectrogram(signal,1024,512,1024,fs);
            imagesc(app.AudioSpectrogram, T, F, log(abs(S))); %plot the log spectrum

            set(app.AudioSpectrogram,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            axis(app.AudioSpectrogram,'tight');
            axis(app.AudioSpectrogram,'square');
            %app.AudioSpectrogram.View = [90 90];
            %colorbar(app.AudioSpectrogram)
            colormap(app.AudioSpectrogram,jet)
        end

        function wavePlot(app)
            %app.Fs %sampling rate in Hz
            signal=app.y(:,1);
            fs=app.Fs;
            N=length(signal); %duration
            %N=T*fs; %number of samples
            dt=1/fs; %time resolution
            t=(0:N-1)*dt; %time vector
            plot(app.AudioWaveform,t,signal);
        end

        function updateApp(app)
            wavePlot(app);
            specPlot2D(app);
            specPlot3D(app);
            app.SamplingFrequencyEditField.Value = num2str(app.Fs);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AudioSpectrogram, LoadFileButton
        function LoadFileButtonPushed(app, event)
            [app.fileName, app.pathName] = uigetfile({'*.mp3;*.m4a'},'Pick an Audio file','MultiSelect','off'); %open load file func
            [app.y,app.Fs] = audioread(app.fileName); %read audio data file @y and frequency sampling @fs
            %app.FileNameWindow.Value = app.fileName; %update file name @text box
            app.player = audioplayer(app.y, app.Fs); %set audio player class
            app.yStatic = app.y;
            %  figure(app.AudioPlayerByDanielMizrahiUIFigure);
            app.AudioPlayerByDanielMizrahiUIFigure.Name = "Audio Player: " + app.fileName;

            % 3 plots initial setups
            updateApp(app)


        end

        % Callback function
        function FileNameWindowValueChanged(app, event)
            %  value = app.FileNameWindow.Value;

        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)

            %            if(flag==0)
            %             app.player = audioplayer(app.y, app.Fs); %reset audio player class
            %            end
            play(app.player);%play audio player
            app.AudioStatusLamp.Color=[0,1,0]; %set audio status lamp to green


            %             signal=app.y(:,1);
            %             fs=app.Fs; %sample rate
            %             N=length(signal); %duration
            %             %N=T*fs; %number of samples
            %             dt=1/fs; %time resolution
            %             t=(0:N-1)*dt; %time vector in seconds
            %time=0::(t(end)); %time vector in seconds for graph plot red line
            %             awfH = plot(app.AudioWaveform,t(1),signal,'MarkerSize' ,20, 'MarkerFaceColor', 'r');%plot first x location
            %             Animation loop -- (3)
%               hold(app.AudioWaveform, 'on' ) %dont allow changes to this plot
%                     xline(app.AudioSpectrogram,2,'Color','r');
%             %  for i = 1:t(end)
%                   %          set(awfH, 'XData', t(i));
%               %              drawnow;
%            %             end
%                hold(app.AudioWaveform, 'off' ) %dont allow changes to this plot


              app.flagSTOPPLOTING =0;
            %app.Fs %sampling rate in Hz
            signal=app.y(:,1);
            fs=app.Fs;
            N=length(signal); %duration
            %N=T*fs; %number of samples
            dt=1/fs; %time resolution
            t=(0:N-1)*dt; %time vector

       
            hold(app.AudioWaveform, 'on' ) %dont allow changes to this plot
            %h = plot(app.AudioWaveform,3,1,'Color','r');
            h = xline(app.AudioWaveform,0,'Color','r');
            for i = 0:0.02:(N)*dt
                if app.flagSTOPPLOTING ==1
                    break
                end%                 h.XData=i;
                %                 h.YData=1;
                %                 drawnow
                %                 pause(dt)
                h.Value=i;
                %h= xline(app.AudioWaveform,i,'Color','r');
                drawnow
               % pause(0.5)

            end
            hold(app.AudioWaveform, 'off' ) %dont allow changes to this plot
            wavePlot(app)
        end

        % Button pushed function: PauseAudioButton
        function PauseAudioButtonPushed(app, event)
            pause(app.player);%pause audio player
            app.AudioStatusLamp.Color=[0.9290, 0.6940, 0.1250]; %set audio status lamp to orange
        end

        % Button pushed function: ResumeAudioButton
        function ResumeAudioButtonPushed(app, event)
            resume(app.player);%resume audio player
            app.AudioStatusLamp.Color=[0,1,0]; %set audio status lamp to green
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            stop(app.player);%stop audio player
            app.AudioStatusLamp.Color=[1,0,0];%set audio status lamp to red
app.flagSTOPPLOTING =1;
        end

        % Value changed function: Boost20dBSwitch
        function Boost20dBSwitchValueChanged(app, event)
            value = app.Boost20dBSwitch.Value;
            if(app.flagM==1)
                return
            end
            if(strcmp(value,'On'))
                %%%%%BROKENNNNNNNNNNNNNNN
                %             pause(app.player);%pause audio player
                %             app.MuteLamp.Color=[1,0,0];%set red mute @lamp
                %             app.currentSample = get(app.player,'CurrentSample');
                %             stop(app.player);%stop audio player
                %            % app.player = audioplayer(app.currentSample .*1.3,app.Fs ); %set audio player class
                %             play(audioplayer(app.currentSample*2,app.Fs ));
                app.y=app.y*1.2;
                app.player = audioplayer(app.y, app.Fs); %reset audio player class
                app.flagR=1;

                updateApp(app)
            else
                %             app.MuteLamp.Color=[0.50,0.50,0.50];%set green unmute @lamp
                %
                %             pause(app.player);%pause audio player
                %             app.MuteLamp.Color=[1,0,0];%set red mute @lamp
                %             app.currentSample = get(app.player,'CurrentSample');
                %             stop(app.player);%stop audio player
                %             app.player = audioplayer(app.currentSample .*0.0000000001,app.Fs ); %set audio player class
                %             play(app.player);
                app.y=app.yStatic;
                app.player = audioplayer(app.y, app.Fs); %reset audio player class
                app.flagR=0;

                updateApp(app)
            end

        end

        % Value changed function: MuteSwitch
        function MuteSwitchValueChanged(app, event)
            value = app.MuteSwitch.Value;
            if(strcmp(value,'On'))
                app.y=app.yStatic *0;
                app.player = audioplayer(app.y, app.Fs); %reset audio player class
                app.flagM=1;
                updateApp(app)
            else
                app.y=app.yStatic;
                app.player = audioplayer(app.y, app.Fs); %reset audio player class
                app.flagM = 0;

                updateApp(app)
            end
        end

        % Value changed function: PlaybackSpeedKnob
        function PlaybackSpeedKnobValueChanged(app, event)
            value = app.PlaybackSpeedKnob.Value;
            if value == 1
                app.y = app.yStatic;
                app.flagS=0;
            else
                app.y = stretchAudio(app.yStatic,value);
                app.flagS=1;
            end
            app.player = audioplayer(app.y, app.Fs);
            updateApp(app)


        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            volume  = app.VolumeKnob.Value;

            if app.flagS == 1
                app.y=app.y *volume;


            else
                app.y=app.yStatic *volume;
            end


            app.player = audioplayer(app.y, app.Fs); %reset audio player class
            updateApp(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioPlayerByDanielMizrahiUIFigure = uifigure('Visible', 'off');
            app.AudioPlayerByDanielMizrahiUIFigure.Name = 'Audio Player By Daniel Mizrahi';
            app.AudioWaveform = uiaxes(app.AudioPlayerByDanielMizrahiUIFigure);
            title(app.AudioWaveform, 'Waveform')
            xlabel(app.AudioWaveform, 'Time [sec]')
            ylabel(app.AudioWaveform, 'Amplitude')
            zlabel(app.AudioWaveform, 'Z')
            app.AudioWaveform.YLim = [-1.2 1.2];
            app.AudioSpectrogram = uiaxes(app.AudioPlayerByDanielMizrahiUIFigure);
            title(app.AudioSpectrogram, 'Spectrogram')
            xlabel(app.AudioSpectrogram, 'Time [sec]')
            ylabel(app.AudioSpectrogram, 'Frequency [kHz]')
            zlabel(app.AudioSpectrogram, 'Z')
            app.AudioSpectrogram.ButtonDownFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.AudioSpectrogram3D = uiaxes(app.AudioPlayerByDanielMizrahiUIFigure);
            title(app.AudioSpectrogram3D, '3D Spectrogram')
            xlabel(app.AudioSpectrogram3D, 'Time [sec]')
            ylabel(app.AudioSpectrogram3D, 'Frequency [kHz]')
            zlabel(app.AudioSpectrogram3D, 'Z')
            app.LoadFileButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PlayAudioButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play';
            app.PauseAudioButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.PauseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAudioButtonPushed, true);
            app.PauseAudioButton.Text = 'Pause';
            app.ResumeAudioButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.ResumeAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeAudioButtonPushed, true);
            app.ResumeAudioButton.Text = 'Resume';
            app.StopAudioButton = uibutton(app.AudioPlayerByDanielMizrahiUIFigure, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop';
            app.RECLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.RECLabel.Text = 'Status';
            app.AudioStatusLamp = uilamp(app.AudioPlayerByDanielMizrahiUIFigure);
            app.Boost20dBSwitchLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.Boost20dBSwitchLabel.Text = 'Boost +20dB';
            app.Boost20dBSwitch = uiswitch(app.AudioPlayerByDanielMizrahiUIFigure, 'toggle');
            app.Boost20dBSwitch.ValueChangedFcn = createCallbackFcn(app, @Boost20dBSwitchValueChanged, true);
            app.MuteLamp = uilamp(app.AudioPlayerByDanielMizrahiUIFigure);
            app.PlaybackSpeedKnobLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.PlaybackSpeedKnobLabel.Text = 'Playback Speed';
            app.PlaybackSpeedKnob = uiknob(app.AudioPlayerByDanielMizrahiUIFigure, 'continuous');
            app.PlaybackSpeedKnob.Limits = [0.5 10];
            app.PlaybackSpeedKnob.MajorTicks = [0.5 1 2.9 4.1 5.3 6.5 7.7 8.9 10];
            app.PlaybackSpeedKnob.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedKnobValueChanged, true);
            app.PlaybackSpeedKnob.Value = 1;
            app.MuteSwitchLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.MuteSwitchLabel.Text = 'Mute';
            app.MuteSwitch = uiswitch(app.AudioPlayerByDanielMizrahiUIFigure, 'toggle');
            app.MuteSwitch.ValueChangedFcn = createCallbackFcn(app, @MuteSwitchValueChanged, true);
            app.VolumeKnobLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.AudioPlayerByDanielMizrahiUIFigure, 'continuous');
            app.VolumeKnob.Limits = [1 10];
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.VolumeKnob.Value = 1;
            app.SamplingFrequencyEditFieldLabel = uilabel(app.AudioPlayerByDanielMizrahiUIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.AudioPlayerByDanielMizrahiUIFigure, 'text');
            app.SamplingFrequencyEditField.Editable = 'off';
            % Show the figure after all components are created
            app.AudioPlayerByDanielMizrahiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testflight

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioPlayerByDanielMizrahiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioPlayerByDanielMizrahiUIFigure)
        end
    end
end